classdef work3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button_38        matlab.ui.control.Button
        Label_8          matlab.ui.control.Label
        Button_37        matlab.ui.control.Button
        Button_36        matlab.ui.control.Button
        Label_7          matlab.ui.control.Label
        HOGButton        matlab.ui.control.Button
        LBPButton        matlab.ui.control.Button
        Label_6          matlab.ui.control.Label
        Button_33        matlab.ui.control.Button
        sobelButton_2    matlab.ui.control.Button
        prewittButton_2  matlab.ui.control.Button
        robertButton_2   matlab.ui.control.Button
        Label_5          matlab.ui.control.Label
        Button_29        matlab.ui.control.Button
        Button_28        matlab.ui.control.Button
        Label_4          matlab.ui.control.Label
        Button_27        matlab.ui.control.Button
        Button_26        matlab.ui.control.Button
        Label_3          matlab.ui.control.Label
        Button_25        matlab.ui.control.Button
        Button_24        matlab.ui.control.Button
        Button_23        matlab.ui.control.Button
        Button_22        matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        Button_19        matlab.ui.control.Button
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function open_img(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像');
            if filename ~= 0
             img = imread(fullfile(pathname, filename));  % 读取图像
             app.Image.ImageSource = img;  % 显示在GUI中的原图区域
            end
        end

        % Button pushed function: Button_36
        function showHistogram(app, event)
            %显示灰度直方图
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            grayImg = rgb2gray(img);
            [counts, binLocations] = imhist(grayImg);
            % 创建直方图图形并绘制
            figure('Visible', 'off');
            bar(binLocations, counts, 'BarWidth', 1, 'FaceColor', 'b');
            title('灰度图像直方图');
            xlabel('灰度级');
            ylabel('像素数');
            frame = getframe(gcf);  
            histImage = frame.cdata;  
            close;
            app.Image2.ImageSource = histImage;
        end

        % Button pushed function: Button_37
        function histogramEqualization(app, event)
            %直方图均衡化
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            grayImg = rgb2gray(img);
            [counts, ~] = imhist(grayImg);
            cdf = cumsum(counts) / numel(grayImg);
            cdfMin = min(cdf(cdf > 0));
            lookupTable = round((cdf - cdfMin) / (1 - cdfMin) * 255);
            equalizedImg = uint8(lookupTable(double(grayImg) + 1));
            % 将灰度图像转换为RGB图像，复制到三个通道
            equalizedRgbImg = repmat(equalizedImg, [1, 1, 3]);
            app.Image2.ImageSource = equalizedRgbImg;
        end

        % Button pushed function: Button_38
        function histogramMatching(app, event)
            %直方图匹配（规定化）
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            grayImg = rgb2gray(img);
            % 加载一张参考图像，用于直方图匹配
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择参考图像');
            if filename == 0
                return;
            end
            refImg = imread(fullfile(pathname, filename));
            refGrayImg = rgb2gray(refImg);
            % 执行直方图匹配
            matchedImg = imhistmatch(grayImg, refGrayImg);
            matchedRgbImg = repmat(matchedImg, [1, 1, 3]);
            app.Image2.ImageSource = matchedRgbImg;
        end

        % Button pushed function: Button_22
        function gray(app, event)
            %灰度化图像
            img = app.Image.ImageSource;
            if ~isempty(img)
                %灰度化
                R = img(:,:,1);  % 红色通道
                G = img(:,:,2);  % 绿色通道
                B = img(:,:,3);  % 蓝色通道
                gray_img = uint8(0.299*double(R) + 0.587*double(G) + 0.114*double(B));
                gray_rgb = repmat(gray_img, [1, 1, 3]);
                app.Image2.ImageSource = gray_rgb;
            end
        end

        % Button pushed function: Button_23
        function linear(app, event)
            %线性变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            grayImg = rgb2gray(img);
            % new_img = a * gray_img + b
            a = 1.5;  % 选择适当的增益
            b = 0;    % 偏移量
            enhanced_img = a * double(grayImg) + b;
            enhanced_img = uint8(min(max(enhanced_img, 0), 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_24
        function log(app, event)
            %对数变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            c = 255 / log(1 + max(double(gray_img(:))));
            enhanced_img = c * log(1 + double(gray_img));
            enhanced_img = uint8(min(enhanced_img, 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_25
        function exp(app, event)
            %指数变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            b = 50;  % 可调节的参数
            c = 255 / (exp(255 / b) - 1);
            enhanced_img = c * (exp(double(gray_img) / b) - 1);
            enhanced_img = uint8(min(enhanced_img, 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_26
        function scale(app, event)
             %缩放变换
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            scaled_img = imresize(img,0.5);
            app.Image2.ImageSource = scaled_img;
        end

        % Button pushed function: Button_27
        function rotate(app, event)
            %旋转变换
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            [original_height, original_width, num_channels] = size(img);
            angle = 90;
            new_height = original_width;
            new_width = original_height;
            rotated_img = zeros(new_height, new_width, num_channels, 'uint8');
            % 旋转矩阵（90度）
            for row = 1:original_height
                for col = 1:original_width
                    new_row = col;
                    new_col = original_height - row + 1;
                    if new_row >= 1 && new_row <= new_height && new_col >= 1 && new_col <= new_width
                        rotated_img(new_row, new_col, :) = img(row, col, :);
                    end
                end
            end
            app.Image2.ImageSource = rotated_img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @open_img, true);
            app.Image2 = uiimage(app.UIFigure);
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.Text = '提取目标';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目标图';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @gray, true);
            app.Button_22.Text = '灰度化图像';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @linear, true);
            app.Button_23.Text = '线性变换';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @log, true);
            app.Button_24.Text = '对数变换';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @exp, true);
            app.Button_25.Text = '指数变换';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '灰度图像';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @scale, true);
            app.Button_26.Text = '缩放变换';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @rotate, true);
            app.Button_27.Text = '旋转变换';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '图像变换';
            app.Button_28 = uibutton(app.UIFigure, 'push');
            app.Button_28.Text = '空域处理';
            app.Button_29 = uibutton(app.UIFigure, 'push');
            app.Button_29.Text = '频域处理';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '图像加噪';
            app.robertButton_2 = uibutton(app.UIFigure, 'push');
            app.robertButton_2.Text = 'robert算子';
            app.prewittButton_2 = uibutton(app.UIFigure, 'push');
            app.prewittButton_2.Text = 'prewitt算子';
            app.sobelButton_2 = uibutton(app.UIFigure, 'push');
            app.sobelButton_2.Text = 'sobel算子';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.Text = '拉普拉斯算子';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '边缘提取';
            app.LBPButton = uibutton(app.UIFigure, 'push');
            app.LBPButton.Text = 'LBP';
            app.HOGButton = uibutton(app.UIFigure, 'push');
            app.HOGButton.Text = 'HOG';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '特征提取';
            app.Button_36 = uibutton(app.UIFigure, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @showHistogram, true);
            app.Button_36.Text = '灰度直方图';
            app.Button_37 = uibutton(app.UIFigure, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @histogramEqualization, true);
            app.Button_37.Text = '直方图均衡化';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '灰度直方';
            app.Button_38 = uibutton(app.UIFigure, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @histogramMatching, true);
            app.Button_38.Text = '直方图匹配';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = work3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end